classdef LabelRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabelRecognitionUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        FileLocationField         matlab.ui.control.EditField
        BrowseButton              matlab.ui.control.Button
        RecogniseDatesButton      matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Properties relating to app data
    properties (Access = private)
        imageMatrix % N-by-M matrix of the user selected image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Select image using GUI
            types = {'*.jpeg;*.jpg;*.png;*.gif;*.tiff', ...
                'Image Files (*.jpeg, *.jpg, *.png, *.gif, *.tiff)'}; 
            [file, path] = uigetfile(types, 'Browse Images');
            
            % Check that a file was selected
            if (~isequal(file, 0))
                filepath = fullfile(path, file);
                
                % NEED TO HANDLE IMAGE READING AND ASSIGNING SOURCE MORE
                % ELEGANTLY - MAYBE IN SAME TRY() WITH A SWTICH(){} 
                try 
                    I = imread(filepath);
                catch ME
                    uialert(app.LabelRecognitionUIFigure, ME.message, 'Invalid File Type');
                end
                
                if size(I, 3) < 3
                    app.Image.ImageSource = cat(3, I, I, I);
                else
                    app.Image.ImageSource = I;
                end                
                % Assign selected file to field and image box
                app.FileLocationField.Value = filepath;
            end
            
            % Return to UIFigure
            drawnow;
            figure(app.LabelRecognitionUIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LabelRecognitionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabelRecognitionUIFigure = uifigure('Visible', 'off');
            app.LabelRecognitionUIFigure.Name = 'Label Recognition';
            app.LabelRecognitionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LabelRecognitionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileLocationField = uieditfield(app.LeftPanel, 'text');
            app.FileLocationField.Editable = 'off';
            app.FileLocationField.Tooltip = {'Image File Path'};
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RecogniseDatesButton = uibutton(app.LeftPanel, 'push');
            app.RecogniseDatesButton.Text = 'Recognise Dates';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.LabelRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabelRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LabelRecognitionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabelRecognitionUIFigure)
        end
    end
end